classdef APMA_2120_Vector_Field_Visualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        DevelopersEditFieldLabel       matlab.ui.control.Label
        HuyNguyenUVACS26LukasLabel_2   matlab.ui.control.Label
        HuyNguyenUVACS26LukasLabel     matlab.ui.control.Label
        DevelopersEditField_2          matlab.ui.control.EditField
        VectorFieldLabel_2             matlab.ui.control.Label
        int_CvecFvecdrLabel            matlab.ui.control.Label
        VectorFieldVisualizationLabel  matlab.ui.control.Label
        HowToUseLabel                  matlab.ui.control.Label
        DevelopersTextArea_6           matlab.ui.control.TextArea
        ProbabilityLabel               matlab.ui.control.Label
        StatisticsLabel                matlab.ui.control.Label
        OrdinaryDifferentialEquationsLabel  matlab.ui.control.Label
        LinearAlgebraLabel             matlab.ui.control.Label
        MultivariableCalculusLabel     matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        DevelopersTextArea_5           matlab.ui.control.TextArea
        DevelopersTextArea_4           matlab.ui.control.TextArea
        DevelopersTextArea_3           matlab.ui.control.TextArea
        DevelopersTextArea_2           matlab.ui.control.TextArea
        DevelopersTextArea             matlab.ui.control.TextArea
        Panel                          matlab.ui.container.Panel
        ColorDropDown                  matlab.ui.control.DropDown
        ColorDropDownLabel             matlab.ui.control.Label
        MaxEditField                   matlab.ui.control.NumericEditField
        MaxEditFieldLabel              matlab.ui.control.Label
        MinEditField                   matlab.ui.control.NumericEditField
        MinEditFieldLabel              matlab.ui.control.Label
        REditField                     matlab.ui.control.EditField
        REditFieldLabel                matlab.ui.control.Label
        QEditField                     matlab.ui.control.EditField
        QEditFieldLabel                matlab.ui.control.Label
        PEditField                     matlab.ui.control.EditField
        PEditFieldLabel                matlab.ui.control.Label
        VectorFieldLabel               matlab.ui.control.Label
        PlotVectorFieldButton          matlab.ui.control.Button
        DevelopersEditField            matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2_2                        matlab.ui.container.Menu
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotVectorFieldButton
        function PlotVectorFieldButtonPushed(app, event)
            % Note: Will add some function calling for comprehension and clean
            % Note: Add the properties of divergence, curl, and conservative vector fields in text box
           
            % Clear the axes before plotting
            cla(app.UIAxes,'reset')

            % Read lower, range, and upper bound
            lower_bound = app.MinEditField.Value;
            upper_bound = app.MaxEditField.Value;
            
            
            % Create the grid
            [matrix_x ,matrix_y, matrix_z] = meshgrid(lower_bound:1:upper_bound);

            
            % Read and Convert user's input from char to string in the text field 
            x_expr = convertCharsToStrings(app.PEditField.Value);
            y_expr = convertCharsToStrings(app.QEditField.Value);
            z_expr = convertCharsToStrings(app.REditField.Value);
            
            % Create zeros 3D array to store the results
            [rows, cols, heights] = size(matrix_x);
            matrix_x_output = zeros(rows, cols, heights);
            matrix_y_output = zeros(rows, cols, heights);
            matrix_z_output = zeros(rows, cols, heights);
            
            % Loop through each element in the orignal array
            % Update the 3D placeholder matrix correspondingly
            for i = 1:rows
                for j = 1:cols
                    for k = 1:heights
                        % Set x, y, z = to the corresponding element to use eval
                        x = matrix_x(i,j,k); y = matrix_y(i,j,k); z = matrix_z(i,j,k);
    
                        % Use eval on the x, y and z element above to
                        % fill up the placeholder 3D array
                        if (x_expr ~= "")
                            matrix_x_output(i,j,k) = eval(x_expr);
                        end

                        if (y_expr ~= "")
                            matrix_y_output(i,j,k) = eval(y_expr);
                        end

                        if (z_expr ~= "")
                           matrix_z_output(i,j,k) = eval(z_expr);
                        end

                    end 
                end
            end
            
            % Color stuff
            if (app.ColorDropDown.Value == "red")
                color = "r";
            elseif (app.ColorDropDown.Value == "blue")
                color = "b";
            elseif (app.ColorDropDown.Value == "green")
                color = "g";
            elseif (app.ColorDropDown.Value == "cyan")
                color = "c";
            elseif (app.ColorDropDown.Value == "purple")
                color = "m";
            elseif (app.ColorDropDown.Value == "yellow")
                color = "y";
            elseif (app.ColorDropDown.Value == "black")
                color = "k";
            end

            % Plot 2D and 3D
            if (z_expr ~= "")
               quiver3(app.UIAxes, matrix_x, matrix_y, matrix_z, ...
                   matrix_x_output, matrix_y_output, matrix_z_output, 1, color)
            else
                quiver(app.UIAxes, matrix_x, matrix_y, matrix_x_output, matrix_y_output, 1, color)
            end
        end

        % Callback function: not associated with a component
        function ExitButtonPushed(app, event)
     

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vector Field Plot for F')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DevelopersEditField = uieditfield(app.UIFigure, 'text');
            app.PlotVectorFieldButton = uibutton(app.UIFigure, 'push');
            app.PlotVectorFieldButton.ButtonPushedFcn = createCallbackFcn(app, @PlotVectorFieldButtonPushed, true);
            app.PlotVectorFieldButton.Text = 'Plot Vector Field';
            app.VectorFieldLabel = uilabel(app.UIFigure);
            app.VectorFieldLabel.Text = 'Vector Field';
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'text');
            app.QEditFieldLabel = uilabel(app.UIFigure);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.UIFigure, 'text');
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'text');
            app.MinEditFieldLabel = uilabel(app.UIFigure);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinEditField.Value = -3;
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxEditField.Value = 3;
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'red', 'blue', 'green', 'cyan', 'purple', 'yellow', 'black'};
            app.ColorDropDown.Value = 'red';
            app.Panel = uipanel(app.UIFigure);
            app.DevelopersTextArea = uitextarea(app.UIFigure);
            app.DevelopersTextArea_2 = uitextarea(app.UIFigure);
            app.DevelopersTextArea_3 = uitextarea(app.UIFigure);
            app.DevelopersTextArea_4 = uitextarea(app.UIFigure);
            app.DevelopersTextArea_5 = uitextarea(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Center for Applied Math logos', 'template-2024-11-05-02-12.jpeg');
            app.MultivariableCalculusLabel = uilabel(app.UIFigure);
            app.MultivariableCalculusLabel.Text = 'Multivariable Calculus';
            app.LinearAlgebraLabel = uilabel(app.UIFigure);
            app.LinearAlgebraLabel.Text = 'Linear Algebra';
            app.OrdinaryDifferentialEquationsLabel = uilabel(app.UIFigure);
            app.OrdinaryDifferentialEquationsLabel.Text = 'Ordinary Differential Equations';
            app.StatisticsLabel = uilabel(app.UIFigure);
            app.StatisticsLabel.Text = 'Statistics';
            app.ProbabilityLabel = uilabel(app.UIFigure);
            app.ProbabilityLabel.Text = 'Probability';
            app.DevelopersTextArea_6 = uitextarea(app.UIFigure);
            app.DevelopersTextArea_6.Value = {''; '1.  For all multiplication, use the * symbol.'; '2.  Adjust bounds numerically for different views.'; '3.  The lower bound should always be smaller than the '; '     upper bound!.'; '4.  Use x, y, and z as variables.'; '5.  Drag the Vector Field Plot to change viewing '; '     perspective.'; '6.  For 2D vectors, omit the Z-component.'; '7.  When plotting some Division Expression as X/Y, '; '     adjust the bounds to some irrational numbers '; '     (from -2.789 to 2.789) to avoid dividing by zeros.'};
            app.HowToUseLabel = uilabel(app.UIFigure);
            app.HowToUseLabel.Text = 'How To Use';
            app.VectorFieldVisualizationLabel = uilabel(app.UIFigure);
            app.VectorFieldVisualizationLabel.Text = 'Vector Field Visualization';
            app.int_CvecFvecdrLabel = uilabel(app.UIFigure);
            app.int_CvecFvecdrLabel.Interpreter = 'latex';
            app.int_CvecFvecdrLabel.Text = '$\vec{F}(x,y,z) = <P, Q, R>$';
            app.VectorFieldLabel_2 = uilabel(app.UIFigure);
            app.VectorFieldLabel_2.Text = 'Vector Field:';
            app.DevelopersEditField_2 = uieditfield(app.UIFigure, 'text');
            app.HuyNguyenUVACS26LukasLabel = uilabel(app.UIFigure);
            app.HuyNguyenUVACS26LukasLabel.Text = {'Artun Duransoy (UVA 27'')'; 'Haolin Chen (UVA 27'')'; 'Zaid Contracter (UVA 27'')'};
            app.HuyNguyenUVACS26LukasLabel_2 = uilabel(app.UIFigure);
            app.HuyNguyenUVACS26LukasLabel_2.Text = {'Huy Nguyen (UVA CS 26'')'; 'Eddie Xiao (UVA 27)'; 'Lukas Kubilius (UVA 27'')'};
            app.DevelopersEditFieldLabel = uilabel(app.UIFigure);
            app.DevelopersEditFieldLabel.Text = 'Developers:';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu);
            app.Menu2_2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIFigure.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APMA_2120_Vector_Field_Visualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end