classdef APMA_2120_Vector_Line_Integral_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HuyNguyenUVACS26LukasLabel_3  matlab.ui.control.Label
        HuyNguyenUVACS26LukasLabel_2  matlab.ui.control.Label
        DevelopersEditField           matlab.ui.control.EditField
        DevelopersEditFieldLabel      matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        ProbabilityLabel              matlab.ui.control.Label
        StatisticsLabel               matlab.ui.control.Label
        OrdinaryDifferentialEquationsLabel  matlab.ui.control.Label
        LinearAlgebraLabel            matlab.ui.control.Label
        MultivariableCalculusLabel    matlab.ui.control.Label
        HowToUseLabel                 matlab.ui.control.Label
        int_CvecFvecdrLabel_2         matlab.ui.control.Label
        aLabel_2                      matlab.ui.control.Label
        bLabel                        matlab.ui.control.Label
        VectorLineIntegralLabel       matlab.ui.control.Label
        VectorFieldLabel              matlab.ui.control.Label
        ParameterizedCurveLabel       matlab.ui.control.Label
        vecFxyzPQRLabel               matlab.ui.control.Label
        vecrtAtBtCtLabel              matlab.ui.control.Label
        int_CvecFvecdrLabel           matlab.ui.control.Label
        CurveUpperBoundEditField      matlab.ui.control.NumericEditField
        CurveUpperBoundLabel          matlab.ui.control.Label
        ZtEditField                   matlab.ui.control.EditField
        ZtEditFieldLabel              matlab.ui.control.Label
        YtEditField                   matlab.ui.control.EditField
        YtEditFieldLabel              matlab.ui.control.Label
        XtEditField                   matlab.ui.control.EditField
        XtEditFieldLabel              matlab.ui.control.Label
        VectorLineIntegralVisualizationLabel  matlab.ui.control.Label
        REditField                    matlab.ui.control.EditField
        REditFieldLabel               matlab.ui.control.Label
        CurveLowerBoundEditField      matlab.ui.control.NumericEditField
        CurveLowerBoundLabel          matlab.ui.control.Label
        ClearButton                   matlab.ui.control.Button
        QEditField                    matlab.ui.control.EditField
        QEditFieldLabel               matlab.ui.control.Label
        IntegralValueEditField        matlab.ui.control.NumericEditField
        CalculateButton               matlab.ui.control.Button
        PEditFieldLabel               matlab.ui.control.Label
        PEditField                    matlab.ui.control.EditField
        Image                         matlab.ui.control.Image
        DevelopersTextArea_2          matlab.ui.control.TextArea
        DevelopersTextArea_3          matlab.ui.control.TextArea
        DevelopersTextArea_4          matlab.ui.control.TextArea
        DevelopersTextArea_5          matlab.ui.control.TextArea
        DevelopersTextArea_6          matlab.ui.control.TextArea
        DevelopersTextArea_7          matlab.ui.control.TextArea
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [matrix_x_output, matrix_y_output, matrix_z_output] = convert_vector(~, ...
                matrix_x, matrix_y, matrix_z, x_expr, y_expr, z_expr)

            [rows, cols, heights] = size(matrix_x);
            matrix_x_output = zeros(rows, cols, heights);
            matrix_y_output = zeros(rows, cols, heights);
            matrix_z_output = zeros(rows, cols, heights);
            
            for i = 1:rows
                for j = 1:cols
                    for k = 1:heights
                        % Set x, y, z = to the corresponding element to use eval
                        x = matrix_x(i,j,k); y = matrix_y(i,j,k); z = matrix_z(i,j,k);
    
                        % Use eval on the x, y and z element above
                        if (x_expr ~= "")
                            matrix_x_output(i,j,k) = eval(x_expr);
                        end

                        if (y_expr ~= "")
                            matrix_y_output(i,j,k) = eval(y_expr);
                        end

                        if (z_expr ~= "")
                           matrix_z_output(i,j,k) = eval(z_expr);
                        end
                    end 
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            lower_bound = app.CurveLowerBoundEditField.Value;
            upper_bound = app.CurveUpperBoundEditField.Value;

            A_path = convertCharsToStrings(app.XtEditField.Value);
            B_path = convertCharsToStrings(app.YtEditField.Value);
            C_path = convertCharsToStrings(app.ZtEditField.Value);
            
            t = lower_bound;
            lower_bound_of_box = min([eval(A_path), eval(B_path), eval(C_path)]);

            t = upper_bound;
            upper_bound_of_box = max([eval(A_path), eval(B_path), eval(C_path)]);

            disp(lower_bound_of_box)
            disp(upper_bound_of_box)

            range = (upper_bound_of_box - lower_bound_of_box) / 5;
            
            [matrix_x ,matrix_y, matrix_z] = meshgrid(lower_bound_of_box:range:upper_bound_of_box);
            
            % Convert User's input from char to string in the text field 
            x_expr = convertCharsToStrings(app.PEditField.Value);
            y_expr = convertCharsToStrings(app.QEditField.Value);
            z_expr = convertCharsToStrings(app.REditField.Value);
            
            % Create zeros array to store the results
            [rows, cols, heights] = size(matrix_x);
            matrix_x_output = zeros(rows, cols, heights);
            matrix_y_output = zeros(rows, cols, heights);
            matrix_z_output = zeros(rows, cols, heights);
            
            % Loop through each element in the array
            for i = 1:rows
                for j = 1:cols
                    for k = 1:heights
                        % Set x, y, z = to the corresponding element to use eval
                        x = matrix_x(i,j,k); y = matrix_y(i,j,k); z = matrix_z(i,j,k);
    
                        % Use eval on the x, y and z element above
                        if (x_expr ~= "")
                            matrix_x_output(i,j,k) = eval(x_expr);
                        end

                        if (y_expr ~= "")
                            matrix_y_output(i,j,k) = eval(y_expr);
                        end

                        if (z_expr ~= "")
                            matrix_z_output(i,j,k) = eval(z_expr);
                        end
                    end
                end
            end
            % Plot
            if (z_expr ~= "")
                % 3D quiver plot with red color
                quiver3(app.UIAxes, matrix_x, matrix_y, matrix_z, ...
                    matrix_x_output, matrix_y_output, matrix_z_output, 'Color', 'b', 'LineWidth', 1.5);
            else
                % 2D quiver plot with red color
                quiver(app.UIAxes, matrix_x, matrix_y, matrix_x_output, matrix_y_output, 'b', 'LineWidth', 1.5);
            end

            hold(app.UIAxes, 'on');

            % % Plot
            % if (z_expr ~= "")
            %     quiver3(app.UIAxes, matrix_x, matrix_y, matrix_z, ...
            %         matrix_x_output, matrix_y_output, matrix_z_output, 1.5, "r")
            % else
            %     quiver(app.UIAxes, matrix_x, matrix_y, matrix_x_output, matrix_y_output, 1.5, "r")
            % end

            % Prompt the user for input functions A, B, C (parameterized curve)
            A_input = app.XtEditField.Value;
            B_input = app.YtEditField.Value;
            C_input = app.ZtEditField.Value;

            % Prompt the user for input functions P, Q, R (vector field)
            P_input = app.PEditField.Value;
            Q_input = app.QEditField.Value;
            R_input = app.REditField.Value;

            % Prompt the user for bounds and grid size
            t_min = app.CurveLowerBoundEditField.Value;
            t_max = app.CurveUpperBoundEditField.Value;

            if (R_input == "")
                % Define the parameterized curve (replace with your expressions)
                A = str2func(['@(t) ' A_input]);
                B = str2func(['@(t) ' B_input]);

                % Define the parameter range
                t = linspace(0, 2*pi, 100);


                P = str2func(['@(x, y) ' P_input]);
                Q = str2func(['@(x, y) ' Q_input]);
                % Evaluate the vector field along the parameterized curve
                P_values = P(A(t), B(t));
                Q_values = Q(A(t), B(t));

                 U = P(x, y, z);
                V = Q(x, y, z);

                % Plot the 2D vector field using quiver on the UIAxes with blue color
                quiver(app.UIAxes, x, y, U, V, 'Color', 'blue', 'LineWidth', 1.5);
                hold(app.UIAxes, 'on');

                % Keep the current plot and add the parameterized curve
                plot(app.UIAxes, x_curve, y_curve, 'r', 'LineWidth', 3);

                title(app.UIAxes, '2D Vector Field with Parameterized Curve');
                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'y');
                grid(app.UIAxes, 'on');
                legend(app.UIAxes, 'Vector Field', 'Parameterized Curve');

                hold(app.UIAxes, 'off');

            else
                % Convert input strings to function handles for the parameterized curve
                A = str2func(['@(t) ' A_input]);
                B = str2func(['@(t) ' B_input]);
                C = str2func(['@(t) ' C_input]);

                % Convert input strings to+ function handles for the vector field
                P = str2func(['@(x, y, z) ' P_input]);
                Q = str2func(['@(x, y, z) ' Q_input]);
                R = str2func(['@(x, y, z) ' R_input]);

                % Create a parameter range for the curve
                t = linspace(t_min, t_max, 100);

                % Evaluate the parameterized curve at each point in the parameter range
                x_curve = A(t);
                y_curve = B(t);
                z_curve = C(t);

%                 % Create a grid for x, y, and z coordinates for the vector field
%                 [x, y, z] = meshgrid(linspace(t_min, t_max, grid_size), ...
%                     linspace(t_min, t_max, grid_size), ...
%                     linspace(t_min, t_max, grid_size));

%                 % Evaluate the vector field components at each point in the grid
%                 U = P(x, y, z);
%                 V = Q(x, y, z);
%                 W = R(x, y, z);

                % Compute the line integral using the parameterized curve and vector field
                dx = gradient(A(t), t);
                dy = gradient(B(t), t);
                dz = gradient(C(t), t);

                integrand = P(x_curve, y_curve, z_curve) .* dx + ...
                    Q(x_curve, y_curve, z_curve) .* dy + ...
                    R(x_curve, y_curve, z_curve) .* dz;

                line_integral_value = trapz(t, integrand);

                app.IntegralValueEditField.Value = line_integral_value;

%                 % Plot the 3D vector field using quiver3 on the UIAxes with blue color
%                 quiver3(app.UIAxes, x, y, z, U, V, W, 'Color', 'blue', 'LineWidth', 1.5);
%                 hold(app.UIAxes, 'on');
%                  % Keep the current plot and add the parameterized curve

                % Plot the 3D parameterized curve on UIAxes
                plot3(app.UIAxes, x_curve, y_curve, z_curve, 'r', 'LineWidth', 3);
                hold(app.UIAxes, 'off');
            end


        end

        % Callback function
        function ExitButtonPushed(app, event)
            closereq();
        end

        % Callback function
        function PlotLineButtonPushed(app, event)


% Prompt the user for input functions A, B, C (parameterized curve)
A_input = input('Enter the function for A(t): ', 's');
B_input = input('Enter the function for B(t): ', 's');
C_input = input('Enter the function for C(t): ', 's');

% Prompt the user for input functions P, Q, R (vector field)
P_input = input('Enter the function for P(x, y, z): ', 's');
Q_input = input('Enter the function for Q(x, y, z): ', 's');
R_input = input('Enter the function for R(x, y, z): ', 's');

% Prompt the user for bounds and grid size
t_min = input('Enter the minimum value for t: ');
t_max = input('Enter the maximum value for t: ');
grid_size = input('Enter the grid size for the vector field: ');

% Convert input strings to function handles for the parameterized curve
A = str2func(['@(t) ' A_input]);
B = str2func(['@(t) ' B_input]);
C = str2func(['@(t) ' C_input]);

% Convert input strings to function handles for the vector field
P = str2func(['@(x, y, z) ' P_input]);
Q = str2func(['@(x, y, z) ' Q_input]);
R = str2func(['@(x, y, z) ' R_input]);

% Create a parameter range for the curve
t = linspace(t_min, t_max, 100);

% Evaluate the parameterized curve at each point in the parameter range
x_curve = A(t);
y_curve = B(t);
z_curve = C(t);

% Create a grid for x, y, and z coordinates for the vector field
[x, y, z] = meshgrid(linspace(t_min, t_max, grid_size), ...
                     linspace(t_min, t_max, grid_size), ...
                     linspace(t_min, t_max, grid_size));

% Evaluate the vector field components at each point in the grid
U = P(x, y, z);
V = Q(x, y, z);
W = R(x, y, z);

% Compute the line integral using the parameterized curve and vector field
dx = gradient(A(t), t);
dy = gradient(B(t), t);
dz = gradient(C(t), t);

integrand = P(x_curve, y_curve, z_curve) .* dx + ...
            Q(x_curve, y_curve, z_curve) .* dy + ...
            R(x_curve, y_curve, z_curve) .* dz;

line_integral_value = trapz(t, integrand);


% Plot the 3D vector field using quiver3
figure;
quiver3(x, y, z, U, V, W, 'LineWidth', 1.5);
hold on; % Keep the current plot and add the parameterized curve

% Plot the 3D parameterized curve
plot3(x_curve, y_curve, z_curve, 'r', 'LineWidth', 2);
title('3D Vector Field with Parameterized Curve');
xlabel('x');
ylabel('y');
zlabel('z');
grid on;
legend('Vector Field', 'Parameterized Curve');

% Display the result of the line integral
fprintf('Line Integral Value: %.4f\n', line_integral_value);

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Vector Field Plot for F')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DevelopersTextArea_7 = uitextarea(app.UIFigure);
            app.DevelopersTextArea_6 = uitextarea(app.UIFigure);
            app.DevelopersTextArea_5 = uitextarea(app.UIFigure);
            app.DevelopersTextArea_4 = uitextarea(app.UIFigure);
            app.DevelopersTextArea_3 = uitextarea(app.UIFigure);
            app.DevelopersTextArea_2 = uitextarea(app.UIFigure);
            app.DevelopersTextArea_2.Value = {''; ''; '1. For any operation between two variables, put a '; '    period before the first variable in the operation.'; '                              x.*y, x.^2, y./z, etc'; '2. For all multiplication, use the * symbol.'; '3. Only functions of t can go within the parameterized '; '    curve section, no x/y/z or scalar values.'; '4. Only functions of x/y/z can go within the vector field '; '    section, scalar values allowed.'; '5. The ''Clear'' button will clear the plot.'};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Center for Applied Math logos', 'template-2024-11-05-02-12.jpeg');
            app.PEditField = uieditfield(app.UIFigure, 'text');
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.IntegralValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.QEditFieldLabel = uilabel(app.UIFigure);
            app.QEditFieldLabel.Text = 'Q';
            app.QEditField = uieditfield(app.UIFigure, 'text');
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.CurveLowerBoundLabel = uilabel(app.UIFigure);
            app.CurveLowerBoundLabel.Text = 'Curve Lower Bound:';
            app.CurveLowerBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurveLowerBoundEditField.Value = -3;
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'text');
            app.VectorLineIntegralVisualizationLabel = uilabel(app.UIFigure);
            app.VectorLineIntegralVisualizationLabel.Text = 'Vector Line Integral Visualization';
            app.XtEditFieldLabel = uilabel(app.UIFigure);
            app.XtEditFieldLabel.Text = 'X(t)';
            app.XtEditField = uieditfield(app.UIFigure, 'text');
            app.YtEditFieldLabel = uilabel(app.UIFigure);
            app.YtEditFieldLabel.Text = 'Y(t)';
            app.YtEditField = uieditfield(app.UIFigure, 'text');
            app.ZtEditFieldLabel = uilabel(app.UIFigure);
            app.ZtEditFieldLabel.Text = 'Z(t)';
            app.ZtEditField = uieditfield(app.UIFigure, 'text');
            app.CurveUpperBoundLabel = uilabel(app.UIFigure);
            app.CurveUpperBoundLabel.Text = 'Curve Upper Bound:';
            app.CurveUpperBoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurveUpperBoundEditField.Value = 3;
            app.int_CvecFvecdrLabel = uilabel(app.UIFigure);
            app.int_CvecFvecdrLabel.Interpreter = 'latex';
            app.int_CvecFvecdrLabel.Text = '$\int_{C} \vec{F}  \vec{dr} = \int_{a}^{b} \vec{F}(\vec{r}(t)) \cdot \vec{r}\,(t)\, dt$';
            app.vecrtAtBtCtLabel = uilabel(app.UIFigure);
            app.vecrtAtBtCtLabel.Interpreter = 'latex';
            app.vecrtAtBtCtLabel.Text = {''; '$\vec{r}(t) = <A(t), B(t), C(t)>$'};
            app.vecFxyzPQRLabel = uilabel(app.UIFigure);
            app.vecFxyzPQRLabel.Interpreter = 'latex';
            app.vecFxyzPQRLabel.Text = {''; '$\vec{F}(x,y,z) = <P, Q, R>$'};
            app.ParameterizedCurveLabel = uilabel(app.UIFigure);
            app.ParameterizedCurveLabel.Text = 'Parameterized Curve';
            app.VectorFieldLabel = uilabel(app.UIFigure);
            app.VectorFieldLabel.Text = 'Vector Field';
            app.VectorLineIntegralLabel = uilabel(app.UIFigure);
            app.VectorLineIntegralLabel.Text = 'Vector Line Integral:';
            app.bLabel = uilabel(app.UIFigure);
            app.bLabel.Text = 'b';
            app.aLabel_2 = uilabel(app.UIFigure);
            app.aLabel_2.Text = 'a';
            app.int_CvecFvecdrLabel_2 = uilabel(app.UIFigure);
            app.int_CvecFvecdrLabel_2.Interpreter = 'latex';
            app.int_CvecFvecdrLabel_2.Text = '$\int_{C} \vec{F}  \vec{dr} = $';
            app.HowToUseLabel = uilabel(app.UIFigure);
            app.HowToUseLabel.Text = 'How To Use';
            app.MultivariableCalculusLabel = uilabel(app.UIFigure);
            app.MultivariableCalculusLabel.Text = 'Multivariable Calculus';
            app.LinearAlgebraLabel = uilabel(app.UIFigure);
            app.LinearAlgebraLabel.Text = 'Linear Algebra';
            app.OrdinaryDifferentialEquationsLabel = uilabel(app.UIFigure);
            app.OrdinaryDifferentialEquationsLabel.Text = 'Ordinary Differential Equations';
            app.StatisticsLabel = uilabel(app.UIFigure);
            app.StatisticsLabel.Text = 'Statistics';
            app.ProbabilityLabel = uilabel(app.UIFigure);
            app.ProbabilityLabel.Text = 'Probability';
            app.Panel = uipanel(app.UIFigure);
            app.DevelopersEditFieldLabel = uilabel(app.UIFigure);
            app.DevelopersEditFieldLabel.Text = 'Developers:';
            app.DevelopersEditField = uieditfield(app.UIFigure, 'text');
            app.HuyNguyenUVACS26LukasLabel_2 = uilabel(app.UIFigure);
            app.HuyNguyenUVACS26LukasLabel_2.Text = {'Artun Duransoy (UVA 27'')'; 'Haolin Chen (UVA 27'')'; 'Zaid Contracter (UVA 27'')'};
            app.HuyNguyenUVACS26LukasLabel_3 = uilabel(app.UIFigure);
            app.HuyNguyenUVACS26LukasLabel_3.Text = {'Huy Nguyen (UVA CS 26'')'; 'Eddie Xiao (UVA 27)'; 'Lukas Kubilius (UVA 27'')'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APMA_2120_Vector_Line_Integral_Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end