classdef FINALNumericalOdyssey < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        howtoplayLabel_3         matlab.ui.control.Label
        howtoplayLabel_2         matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        TextArea_2               matlab.ui.control.TextArea
        Image                    matlab.ui.control.Image
        ClearButton              matlab.ui.control.Button
        TimerLabel               matlab.ui.control.Label
        howtoplayLabel           matlab.ui.control.Label
        Score0Label              matlab.ui.control.Label
        StartNewGameButton       matlab.ui.control.Button
        DifficultyDropDown       matlab.ui.control.DropDown
        DifficultyDropDownLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Grid               % Matrix of numbers
        GridSize           % Size of the grid (3, 5, or 7)
        PlayerPosition     % [row, col]
        Score              % Score tracker
        BestScore          % Best score tracker  % Added to track best score
        Path               % Store the player's path  % Added for path visualization
        bestWay = "";
        TimerObj timer
        TimeLeft double = 30
        LastKeyPressTime double = 0;
        ProcessingKeyPress logical = false;
        GameOver logical = false; % New flag


    end

    
    methods (Access = private)
        
        function updateGridDisplay(app)
            % Clear previous display
            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
        
            % Draw grid cells with background color
            for i = 1:app.GridSize
                for j = 1:app.GridSize
                    % Draw cell background
                    rectangle(app.UIAxes, 'Position', [j-0.5, app.GridSize-i+0.5, 1, 1], ...
                        'FaceColor', [0.95 0.95 0.95], 'EdgeColor', 'k');
                    % Highlight start and goal
                    if i == 1 && j == 1
                        rectangle(app.UIAxes, 'Position', [j-0.5, app.GridSize-i+0.5, 1, 1], ...
                            'FaceColor', [0.5 1 0.5], 'EdgeColor', 'k');
                    elseif i == app.GridSize && j == app.GridSize
                        rectangle(app.UIAxes, 'Position', [j-0.5, app.GridSize-i+0.5, 1, 1], ...
                            'FaceColor', [1 0.8 0], 'EdgeColor', 'k');
                    end
                end
            end
        
            % Draw grid lines
            for i = 0:app.GridSize
                plot(app.UIAxes, [0.5, app.GridSize + 0.5], [i + 0.5, i + 0.5], 'k'); % Horizontal
                plot(app.UIAxes, [i + 0.5, i + 0.5], [0.5, app.GridSize + 0.5], 'k'); % Vertical
            end
        
            % Display numbers with color coding
            for i = 1:app.GridSize
                for j = 1:app.GridSize
                    num = app.Grid(i, j);
                    text(app.UIAxes, j, app.GridSize - i + 1, num2str(num), ...
                        'FontSize', 30, 'FontWeight', 'bold', 'HorizontalAlignment', 'center');
                end
            end
        
            % Draw player's path
            if ~isempty(app.Path)
                pathX = app.Path(:, 2);
                pathY = app.GridSize - app.Path(:, 1) + 1;
                plot(app.UIAxes, pathX, pathY, 'b-', 'LineWidth', 2);
            end
        
            % Draw player
            plot(app.UIAxes, app.PlayerPosition(2), app.GridSize - app.PlayerPosition(1) + 1, ...
                 'bo', 'MarkerSize', 50, 'LineWidth', 2);
        
            % Set axis
            axis(app.UIAxes, [0.5, app.GridSize + 0.5, 0.5, app.GridSize + 0.5]);
            axis(app.UIAxes, 'square');
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.XColor = 'none';
            app.UIAxes.YColor = 'none';
            app.UIAxes.Title.String = ''; % Remove default title
        
            hold(app.UIAxes, 'off');
        end


        function updateScoreDisplay(app)
            app.Score0Label.Text = ['Score: ', num2str(app.Score)];
        end


        function movePlayer(app, direction)
            if app.GameOver
                return;
            end
            row = app.PlayerPosition(1);
            col = app.PlayerPosition(2);
        
            % Update position based on movement direction
            switch direction
                case 'right'
                    if col < app.GridSize
                        col = col + 1;
                    end
                case 'down'
                    if row < app.GridSize
                        row = row + 1;
                    end
            end
        
            % Update player position and score
            app.PlayerPosition = [row, col];
            app.Score = app.Score + app.Grid(row, col);
        
            % Update path
            app.Path(end+1, :) = app.PlayerPosition;
        
            % Update UI
            updateGridDisplay(app);
            updateScoreDisplay(app);
            app.bestWay="";
            % Check if player reaches goal
            if row == app.GridSize && col == app.GridSize
                app.GameOver = true;
                if ~isempty(app.TimerObj) && isvalid(app.TimerObj)
                    stop(app.TimerObj)
                    delete(app.TimerObj)
                end
                maxPossible = computeMaxScore(app);
                if app.Score > app.BestScore
                    app.BestScore = app.Score;
                end

                % Choose ending statement based on optimality
                if app.Score == maxPossible
                    endingMsg = sprintf('Congratulations, adventurer—your flawless moves earned the optimal sum of %d!', app.Score);
                else
                    endingMsg = sprintf('Your journey ends with a score of %d—the optimal path eluded you; try again!', app.Score);
                end
                msg = sprintf(['<html>%s<br><br>Max Possible: %d</html>'], endingMsg, maxPossible);

                
                choice = uiconfirm(app.UIFigure, msg, 'Game Over', ...
                    'Options', {'Reset','Reveal Best Path','Close'}, ...
                    'DefaultOption', 'Close', ...
                    'CancelOption', 'Close', ...
                    'Interpreter','html');
                
                if strcmp(choice, 'Reset')
                    t = timer( ...
                        'StartDelay', 0.25, ...
                        'ExecutionMode', 'singleShot', ...
                        'TimerFcn', @(~,~)resetGame(app));
                    start(t);
                elseif strcmp(choice, 'Reveal Best Path')
                    revealBestPath(app);
                end
                app.bestWay = "";
            end
        end

        function maxScore = computeMaxScore(app)
            backtrack = [];
            dp = zeros(app.GridSize);
            dp(1,1) = app.Grid(1,1);
            
            % Fill first row
            for j = 2:app.GridSize
                dp(1,j) = dp(1,j-1) + app.Grid(1,j);
            end
        
            % Fill first column
            for i = 2:app.GridSize
                dp(i,1) = dp(i-1,1) + app.Grid(i,1);
            end
        
            % Fill rest
            for i = 2:app.GridSize
                for j = 2:app.GridSize
                    dp(i,j) = max(dp(i-1,j), dp(i,j-1)) + app.Grid(i,j);
                end
            end
        
            maxScore = dp(app.GridSize, app.GridSize);
            
            i = app.GridSize; j = app.GridSize;
            while i > 1 || j > 1
                if i == 1
                    backtrack(end+1) = 1;
                    j = j-1;
                elseif j == 1
                    backtrack(end+1) = 2;
                    i = i-1;
                else
                    if dp(i, j-1) > dp(i-1, j)
                        backtrack(end+1) = 1;
                        j =j-1;
                    else 
                        backtrack(end+1) = 2;
                        i = i-1;
                    end
                end
            end

            bestPath = fliplr(backtrack);
            

            for k = 1:length(bestPath)
                if bestPath(k) == 1
                    app.bestWay = app.bestWay + "Right ";
                else
                    app.bestWay = app.bestWay + "Down ";
                end
            end
        end

        function resetGame(app)
            if isempty(app.Grid)
                return;
            end
            if ~isempty(app.TimerObj) && isvalid(app.TimerObj)
                 stop(app.TimerObj);
                 delete(app.TimerObj);
                 app.TimerObj = [];
            end

            app.PlayerPosition = [1, 1];
            app.Score = app.Grid(1,1);
            app.Path = [1, 1];
            app.GameOver = false;
            updateGridDisplay(app);
            updateScoreDisplay(app);
            figure(app.UIFigure)
            drawnow;
        end


        function updateTimer(app)
            if app.GameOver
               return; % Prevent multiple popups
            end
            app.bestWay="";
            app.TimeLeft = app.TimeLeft - 1;
            app.TimerLabel.Text = sprintf('Time: %d', app.TimeLeft);
            maxPossible = computeMaxScore(app);
            
            if app.TimeLeft <= 0
                app.GameOver = true;
                stop(app.TimerObj);
                delete(app.TimerObj);
                % Choose ending statement based on optimality
                if app.Score == maxPossible
                    endingMsg = sprintf('Congratulations, adventurer—your flawless moves earned the optimal sum of %d!', app.Score);
                else
                    endingMsg = sprintf('Your journey ends with a score of %d—the optimal path eluded you; try again!', app.Score);
                end
                msg = sprintf(['<html>%s<br><br>Max Possible: %d</html>'], endingMsg, maxPossible);
                
                choice = uiconfirm(app.UIFigure, msg, 'Game Over', ...
                    'Options', {'Reset','Reveal Best Path','Close'}, ...
                    'DefaultOption', 'Close', ...
                    'CancelOption', 'Close', ...
                    'Interpreter','html');
                
                if strcmp(choice, 'Reset')
                    resetGame(app);
                elseif strcmp(choice, 'Reveal Best Path')
                    revealBestPath(app);
                end
                app.bestWay = "";
            end
        
            disp(app.TimeLeft)
        end

        function revealBestPath(app)
            % Compute dp table for the best path
            dp = zeros(app.GridSize);
            dp(1,1) = app.Grid(1,1);
            for j = 2:app.GridSize
                dp(1,j) = dp(1,j-1) + app.Grid(1,j);
            end
            for i = 2:app.GridSize
                dp(i,1) = dp(i-1,1) + app.Grid(i,1);
            end
            for i = 2:app.GridSize
                for j = 2:app.GridSize
                    dp(i,j) = max(dp(i-1,j), dp(i,j-1)) + app.Grid(i,j);
                end
            end

            % Backtrack to determine the best path
            i = app.GridSize; j = app.GridSize;
            moves = [];
            while i > 1 || j > 1
                if i == 1
                    moves(end+1) = 1;  % move right
                    j = j-1;
                elseif j == 1
                    moves(end+1) = 2;  % move down
                    i = i-1;
                else
                    if dp(i, j-1) > dp(i-1, j)
                        moves(end+1) = 1;
                        j = j-1;
                    else
                        moves(end+1) = 2;
                        i = i-1;
                    end
                end
            end
            moves = fliplr(moves);

            % Compute the coordinates along the best path
            bestPathCoords = zeros(length(moves)+1, 2);
            bestPathCoords(1,:) = [1, 1];  % starting point
            currentRow = 1;
            currentCol = 1;
            for k = 1:length(moves)
                if moves(k) == 1
                    currentCol = currentCol + 1;
                elseif moves(k) == 2
                    currentRow = currentRow + 1;
                end
                bestPathCoords(k+1,:) = [currentRow, currentCol];
            end

            % Redraw grid and overlay best path in red
            updateGridDisplay(app);
            hold(app.UIAxes, 'on');
            pathX = bestPathCoords(:, 2);
            pathY = app.GridSize - bestPathCoords(:, 1) + 1;
            plot(app.UIAxes, pathX, pathY, 'r-', 'LineWidth', 3);
            hold(app.UIAxes, 'off');
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartNewGameButton
        function StartNewGameButtonPushed(app, event)
        % Set grid size based on difficulty
            difficulty = app.DifficultyDropDown.Value;
            if strcmp(difficulty, 'Easy (3x3)')
                app.TimeLeft = 60;
                app.GridSize = 3;
            elseif strcmp(difficulty, 'Medium (5x5)')
                app.TimeLeft = 90;
                app.GridSize = 5;
            else
                app.TimeLeft = 120;
                app.GridSize = 7;
            end
            app.GameOver = false;
            app.LastKeyPressTime = tic;
            

            % Generate a new random grid with balanced numbers
            app.Grid = randi([-3, 7], app.GridSize, app.GridSize); % Reduced range for balance
            app.PlayerPosition = [1, 1];
            app.Score = app.Grid(1,1);
            app.Path = [1, 1]; % Initialize path
        
            % Update the grid display
            updateGridDisplay(app);
            updateScoreDisplay(app);

            if ~isempty(app.TimerObj) && isvalid(app.TimerObj)
                stop(app.TimerObj)
                delete(app.TimerObj)
            end

            app.TimerLabel.Text = sprintf('Time: %d', app.TimeLeft);

            app.TimerObj = timer( ...
                'ExecutionMode', 'fixedSpacing', ...   
                'Period', 1, ...                       
                'TasksToExecute', inf, ...             
                'TimerFcn', @(~,~)updateTimer(app));   
            start(app.TimerObj);
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
        
            persistent lastPressTime;
            if isempty(lastPressTime)
                lastPressTime = tic;  % initialize the timer
            end
            % If less than 0.2 seconds have passed since the last event, ignore this one.
            if toc(lastPressTime) < 0.2
                return;
            end
            lastPressTime = tic;  % update the timer
            
            switch event.Key
                case {'rightarrow', 'd'}
                    movePlayer(app, 'right');
                case {'downarrow', 's'}
                    movePlayer(app, 'down');
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.TimerObj) && isvalid(app.TimerObj)
                stop(app.TimerObj)
                delete(app.TimerObj)
            end
            delete(app)
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            try
                if ~isempty(app.TimerObj) && isvalid(app.TimerObj)
                    stop(app.TimerObj);
                    delete(app.TimerObj);
                    app.TimerObj = [];
                end
        
                if isempty(app.Grid)
                    
                    return;
                end
        
                resetGame(app);
        
            catch ME
                % % Graceful recovery and debug message
                % disp("Error during clear:");
                % disp(ME.message);
                % uialert(app.UIFigure, 'An error occurred while clearing the game. Please try restarting.', 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.DifficultyDropDownLabel = uilabel(app.UIFigure);
            app.DifficultyDropDownLabel.Text = 'Difficulty';
            app.DifficultyDropDown = uidropdown(app.UIFigure);
            app.DifficultyDropDown.Items = {'Easy (3x3)', 'Medium (5x5)', 'Hard (7x7)'};
            app.DifficultyDropDown.Value = 'Easy (3x3)';
            app.StartNewGameButton = uibutton(app.UIFigure, 'push');
            app.StartNewGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewGameButtonPushed, true);
            app.StartNewGameButton.Text = 'Start New Game';
            app.Score0Label = uilabel(app.UIFigure);
            app.Score0Label.Text = 'Score: 0';
            app.howtoplayLabel = uilabel(app.UIFigure);
            app.howtoplayLabel.Text = {'Directions:'; ''; '- You start in the top‑left cell and must reach the bottom‑right.'; ''; '- Each step adds its number to your score.'; ''; '- Press D or → to move right, S or ↓ to move down.'; ''; '- You can’t go backwards.'; ''; '- Hurry! The clock is ticking.'};
            app.TimerLabel = uilabel(app.UIFigure);
            app.TimerLabel.Text = 'Timer:';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'template-2024-11-05-02-12.jpeg');
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Numerical Odyssey: A Quest for the Ultimate Sum'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.howtoplayLabel_2 = uilabel(app.UIFigure);
            app.howtoplayLabel_2.Text = {'Enoch Liu'; 'Haolin Chen'; 'Lukas Kubilius'; 'Artun Duransoy'};
            app.howtoplayLabel_3 = uilabel(app.UIFigure);
            app.howtoplayLabel_3.Text = 'Developers:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FINALNumericalOdyssey

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end